classdef Parcial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        rDropDown           matlab.ui.control.DropDown
        rDropDownLabel      matlab.ui.control.Label
        RecordButton        matlab.ui.control.Button
        SNR_BButton_4       matlab.ui.control.Button
        SNR_PButton_4       matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        TButton_5           matlab.ui.control.Button
        SNR_BButton_3       matlab.ui.control.Button
        SNR_PButton_3       matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        TButton_4           matlab.ui.control.Button
        SNR_BButton_2       matlab.ui.control.Button
        SNR_PButton_2       matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        TButton_3           matlab.ui.control.Button
        SNR_BButton         matlab.ui.control.Button
        SNR_PButton         matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        TButton_2           matlab.ui.control.Button
        Button              matlab.ui.control.Button
        ESPButton           matlab.ui.control.Button
        FButton             matlab.ui.control.Button
        TButton             matlab.ui.control.Button
        rpDropDown          matlab.ui.control.DropDown
        rpDropDownLabel     matlab.ui.control.Label
        aEditField          matlab.ui.control.NumericEditField
        aEditFieldLabel     matlab.ui.control.Label
        uEditField          matlab.ui.control.NumericEditField
        uEditFieldLabel     matlab.ui.control.Label
        TcEditField         matlab.ui.control.NumericEditField
        TcEditFieldLabel    matlab.ui.control.Label
        FsEditField         matlab.ui.control.NumericEditField
        Fs80EditFieldLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
        facada              matlab.ui.control.UIAxes
        facfi               matlab.ui.control.UIAxes
        leya                matlab.ui.control.UIAxes
        leyu                matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function snrp = fsnr(~,X,Y)
            e1=Y-X;
            E1=sum(X.*X);
            Ee=sum(e1.*e1)+1e-20;
            snrp=10*log10(E1/Ee);
        end
        function [SNRf,y] = fef(~,rp,x)
            TAMX=length(x);  % tamaño del vector original
            y=[];  
            SNRf=[];
            fe=max(abs(x));%valor inicial
            P=length(x)/40; % tamaño de bloque
            for i=1:P:TAMX
                X1=x(i:i+P-1); %Extracción del bloque del vector original.
                V=round(X1*((2^(rp-1))-1)/fe); % Cuantización del bloque a un rp fijo
                V=V*fe/((2^(rp-1))-1);        % Descuantización
                e1=V-X1;
                E1=sum(X1.*X1);
                Ee=sum(e1.*e1)+1e-20;
                S1=10*log10(E1/Ee);
                S2=S1*ones(length(X1),1);
                SNRf=[SNRf;S2]; %Vector resultante de la SNR en el tiempo 
                y=[y;V]; %Vector resultante recuantizado que será reproducido.     
            end
        end
        
        function [SNRa,Yf] = fea(~,rp,x)
            TAMX=length(x);  % tamaño del vector original
            Yf=[];  
            SNRa=[];
            fe=1;%valor inicial
            P=length(x)/10; % tamaño de bloque
            for i=1:P:TAMX
                X1=x(i:i+P-1); %Extracción del bloque del vector original.
                fe=max(abs(X1)); % Cálculo del factor de escala del bloque
                V=round(X1*((2^(rp-1))-1)/fe); % Cuantización del bloque a un rp fijo
                V=V*fe/((2^(rp-1))-1);        % Descuantización
                e1=V-X1;
                E1=sum(X1.*X1);
                Ee=sum(e1.*e1)+1e-20;
                S1=10*log10(E1/Ee);
                S2=S1*ones(length(X1),1);
                SNRa=[SNRa;S2]; %Vector resultante de la SNR en el tiempo 
                Yf=[Yf;V]; %Vector resultante recuantizado que será reproducido.     
            end
        end
        
        function [SNRu,Yf] = leyU(~,rp,x,u)
            C= log(1+u);
            
            Y_comp = sign(x).*(log(1+u*(abs(x)))./C);
            aux1 =((2^(rp-1))-1); 
            Yq = round(Y_comp.*aux1);
            Yd = Yq./aux1;
            Yf = sign(Yd).*(((exp(C*abs(Yd))-1))/u);

            SNRu = snr(x,Yf);
        end
        
        function [SNRa,xr] = leyA(~,rp,x,A)

            y = zeros(length(x),1);
            aux1 = 1 /A ;
            ip1 = find(abs(x) <= aux1);
            ip2 = find( aux1  < abs(x));
            
            aux11 = log(A);
            aux12 = 1 + aux11;
            aux13 = A / aux12;
            
            y(ip1) = aux13*x(ip1);
            y(ip2) = (sign(x(ip2))/(1+log(A))).*(1+log(A*abs(x(ip2))));
            
            auxA = (2^(rp-1)) -1;
            y = round(y*auxA);
            
            aux1 = 2^(rp-1);
            aux2 = aux1 - 1;
            aux12 = 1 + log(A);
            aux3 = 1/aux12;
            aux13 = aux12/A;
            aux21 = 1 + log(A);
            
            y1 = y / aux2 ;
            xr = zeros(length(y1),1);
            
            ip1 = find(abs(y1) <= aux3 );
            ip2 = find(aux3   < abs(y1));
            
            xr(ip1) =  aux13*y1(ip1);
            xr(ip2) = (sign(y1(ip2)).*exp((aux21*abs(y1(ip2)))-1))/A;
            
            SNRa = snr(x,xr);
        end
        
        function [X,FREC] = fourier(~,x,Fs)
            M= length(x);  %Cuál es el tamaño del bloque de entrada
            n_m = 2^nextpow2(M);
        
            X = fft(x,n_m);
        
            X = X(1:n_m/2+1);  %Región de interés de 0-pi
            d_f = Fs/n_m;  %Resolución de la frecuencia
        
            FREC = 0:d_f:d_f*(n_m/2);  %Vector Frecuencias
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            %Definiciones
            Fs = app.FsEditField.Value();
            r = str2double(app.rDropDown.Value());
            Tc = app.TcEditField.Value();
            rp = str2double(app.rpDropDown.Value());
            u = app.uEditField.Value();
            A = app.aEditField.Value();

            grabadora = audiorecorder(Fs,r,1,1);
            record(grabadora,Tc);
            pause(Tc+1);
            x=getaudiodata(grabadora,"double");
            N=length(x);
            t=0:1/Fs:(N-1)/Fs;
            audiowrite('x.wav',x,Fs);               %Grabado de x
            %Calculos Grabaciones
            [~,x1_04_recuantizado]=leyU(app,4,x,u);
            [~,x1_08_recuantizado]=leyU(app,8,x,u);
            [~,x1_14_recuantizado]=leyU(app,14,x,u);
            [~,x1_18_recuantizado]=leyU(app,18,x,u);
            [~,x2_04_recuantizado]=leyA(app,4,x,A);
            [~,x2_08_recuantizado]=leyA(app,8,x,A);
            [~,x2_14_recuantizado]=leyA(app,14,x,A);
            [~,x2_18_recuantizado]=leyA(app,18,x,A);
            [~,x3_04_recuantizado]=fef(app,4,x);
            [~,x3_08_recuantizado]=fef(app,8,x);
            [~,x3_14_recuantizado]=fef(app,14,x);
            [~,x3_18_recuantizado]=fef(app,18,x);
            [~,x4_04_recuantizado]=fea(app,4,x);
            [~,x4_08_recuantizado]=fea(app,8,x);
            [~,x4_14_recuantizado]=fea(app,14,x);
            [~,x4_18_recuantizado]=fea(app,18,x);

            %Grabaciones
            
            audiowrite('x1_18.wav', x1_18_recuantizado,Fs)
            audiowrite('x1_14.wav', x1_14_recuantizado,Fs)
            audiowrite('x1_08.wav', x1_08_recuantizado,Fs)
            audiowrite('x1_04.wav', x1_04_recuantizado,Fs)
            audiowrite('x2_18.wav', x2_18_recuantizado,Fs)
            audiowrite('x2_14.wav', x2_14_recuantizado,Fs)
            audiowrite('x2_08.wav', x2_08_recuantizado,Fs)
            audiowrite('x2_04.wav', x2_04_recuantizado,Fs)
            audiowrite('x3_18.wav', x3_18_recuantizado,Fs)
            audiowrite('x3_14.wav', x3_14_recuantizado,Fs)
            audiowrite('x3_08.wav', x3_08_recuantizado,Fs)
            audiowrite('x3_04.wav', x3_04_recuantizado,Fs)
            audiowrite('x4_18.wav', x4_18_recuantizado,Fs)
            audiowrite('x4_14.wav', x4_14_recuantizado,Fs)
            audiowrite('x4_08.wav', x4_08_recuantizado,Fs)
            audiowrite('x4_04.wav', x4_04_recuantizado,Fs)


            %Graficos

            [~,U] = leyU(app,rp,x,u);
            [~,A] = leyA(app,rp,x,A);
            [~,Fi] = fef(app,rp,x);
            [~,Ada] = fea(app,rp,x);

            app.UIAxes.Visible="on";
            cla(app.UIAxes,"reset");
            plot(app.UIAxes,t,x,'b'),title(app.UIAxes,'Señal de Entrada')

            app.leya.Visible="on";
            cla(app.leya,"reset");
            plot(app.leya,t,U,'b');title(app.facfi,'Factor Escala Ley u')

            app.leyu.Visible="on";
            cla(app.leyu,"reset");
            plot(app.leyu,t,A,'b');title(app.facada,'Factor Escala Ley A')

            app.facfi.Visible="on";
            cla(app.facfi,"reset");
            plot(app.facfi,t,Fi,'b');title(app.facfi,'Factor Escala Fijo')

            app.facfi.Visible="on";
            cla(app.facada,"reset");
            plot(app.facada,t,Ada,'b');title(app.facada,'Factor Escala Adaptativo')


        end

        % Button pushed function: TButton
        function TButtonPushed(app, event)
            [x,Fs]=audioread("x.wav");
            Tc=app.TcEditField.Value();
            t=0:1/Fs:Tc-(1/Fs);
            
            app.UIAxes.Visible="on";
            cla(app.UIAxes,"reset");
            plot(app.UIAxes,t,x,'b'),title(app.UIAxes,'Señal de Entrada')
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            [x,Fs]=audioread("x.wav");
            [X,F] = fourier(app,x,Fs);

            app.UIAxes.Visible="on";
            cla(app.UIAxes,"reset");
            plot(app.UIAxes,F,abs(X),'b'),title(app.UIAxes,'Señal de Entrada [Hz]')
        end

        % Button pushed function: ESPButton
        function ESPButtonPushed(app, event)
            [x,Fs]=audioread("x.wav");

            app.UIAxes.Visible='on';
            cla(app.UIAxes,'reset');
            spectrogram(x,Fs,'yaxis'),title(app.UIAxes,'Espectrograma de la Señal');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [x,Fs]=audioread("x.wav");
            sound(x,Fs)
        end

        % Button pushed function: TButton_2
        function TButton_2Pushed(app, event)
            rp=str2double(app.rpDropDown.Value());
            Tc=app.TcEditField.Value();

            if rp==4
                [x,Fs]=audioread("x1_04.wav");
            elseif rp==8
                [x,Fs]=audioread("x1_08.wav");
            elseif rp==14
                [x,Fs]=audioread("x1_14.wav");
            elseif rp==18
                [x,Fs]=audioread("x1_18.wav");
            end
            
            t=0:1/Fs:Tc-(1/Fs);
            app.leyu.Visible="on";
            cla(app.leyu,"reset");
            plot(app.leyu,t,x,'b'),title(app.leyu,'Factor Escala Ley u')
        end

        % Button pushed function: TButton_3
        function TButton_3Pushed(app, event)
            rp=str2double(app.rpDropDown.Value());
            Tc=app.TcEditField.Value();

            if rp==4
                [x,Fs]=audioread("x2_04.wav");
            elseif rp==8
                [x,Fs]=audioread("x2_08.wav");
            elseif rp==14
                [x,Fs]=audioread("x2_14.wav");
            elseif rp==18
                [x,Fs]=audioread("x2_18.wav");
            end
            
            t=0:1/Fs:Tc-(1/Fs);
            app.leya.Visible="on";
            cla(app.leya,"reset");
            plot(app.leya,t,x,'b'),title(app.leya,'Factor Escala Ley A')
        end

        % Button pushed function: TButton_4
        function TButton_4Pushed(app, event)
            rp=str2double(app.rpDropDown.Value());
            Tc=app.TcEditField.Value();

            if rp==4
                [x,Fs]=audioread("x3_04.wav");
            elseif rp==8
                [x,Fs]=audioread("x3_08.wav");
            elseif rp==14
                [x,Fs]=audioread("x3_14.wav");
            elseif rp==18
                [x,Fs]=audioread("x3_18.wav");
            end
            
            t=0:1/Fs:Tc-(1/Fs);
            app.facfi.Visible="on";
            cla(app.facfi,"reset");
            plot(app.facfi,t,x,'b'),title(app.facfi,'Factor Escala Fijo')
        end

        % Button pushed function: TButton_5
        function TButton_5Pushed(app, event)
            rp=str2double(app.rpDropDown.Value());
            
            if rp==4
                [x,Fs]=audioread("x4_04.wav");
            elseif rp==8
                [x,Fs]=audioread("x4_08.wav");
            elseif rp==14
                [x,Fs]=audioread("x4_14.wav");
            elseif rp==18
                [x,Fs]=audioread("x4_18.wav");
            end

            N=length(x);
            t=0:1/Fs:(N-1)/Fs;
            app.facada.Visible="on";
            cla(app.facada,"reset");
            plot(app.facada,t,x,'b'),title(app.facada,'Factor Escala Adaptativo')
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            rp=str2double(app.rpDropDown.Value());

            if rp==4
                [x,Fs]=audioread("x1_04.wav");
            elseif rp==8
                [x,Fs]=audioread("x1_08.wav");
            elseif rp==14
                [x,Fs]=audioread("x1_14.wav");
            elseif rp==18
                [x,Fs]=audioread("x1_18.wav");
            end
            sound(x,Fs)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            rp=str2double(app.rpDropDown.Value());

            if rp==4
                [x,Fs]=audioread("x2_04.wav");
            elseif rp==8
                [x,Fs]=audioread("x2_08.wav");
            elseif rp==14
                [x,Fs]=audioread("x2_14.wav");
            elseif rp==18
                [x,Fs]=audioread("x2_18.wav");
            end
            sound(x,Fs)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            rp=str2double(app.rpDropDown.Value());

            if rp==4
                [x,Fs]=audioread("x3_04.wav");
            elseif rp==8
                [x,Fs]=audioread("x3_08.wav");
            elseif rp==14
                [x,Fs]=audioread("x3_14.wav");
            elseif rp==18
                [x,Fs]=audioread("x3_18.wav");
            end
            sound(x,Fs)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            rp=str2double(app.rpDropDown.Value());

            if rp==4
                [x,Fs]=audioread("x4_04.wav");
            elseif rp==8
                [x,Fs]=audioread("x4_08.wav");
            elseif rp==14
                [x,Fs]=audioread("x4_14.wav");
            elseif rp==18
                [x,Fs]=audioread("x4_18.wav");
            end
            sound(x,Fs)
        end

        % Button pushed function: SNR_PButton
        function SNR_PButtonPushed(app, event)
%             rp=str2double(app.rpDropDown.Value());
            u=app.uEditField.Value();
            [x,~]=audioread("x.wav");
%             [SNR,~]=leyU(app,rp,x,u);

            [~,Y4] = leyU(app,4,x,u);
            [~,Y8] = leyU(app,8,x,u);
            [~,Y14] = leyU(app,14,x,u);
            [~,Y18] = leyU(app,18,x,u);
            vc=[4 8 14 18];
            SNR=[fsnr(app,x,Y4),fsnr(app,x,Y8),fsnr(app,x,Y14),fsnr(app,x,Y18)];
            
            app.leyu.Visible="on";
            cla(app.leyu,"reset");
            stem(app.leyu,vc,SNR,'b'),title(app.leyu,'SNR Promedio Ley u')
%             stem(app.leyu,rp,SNR,'b'),title(app.leyu,'SNR Promedio Ley u')
        end

        % Button pushed function: SNR_PButton_2
        function SNR_PButton_2Pushed(app, event)
%             rp=str2double(app.rpDropDown.Value());
            a=app.aEditField.Value();
            [x,~]=audioread("x.wav");
%             [SNR,~]=leyA(app,rp,x,a);
            
            [~,Y4] = leyA(app,4,x,a);
            [~,Y8] = leyA(app,8,x,a);
            [~,Y14] = leyA(app,14,x,a);
            [~,Y18] = leyA(app,18,x,a);
            vc=[4 8 14 18];
            SNR=[fsnr(app,x,Y4),fsnr(app,x,Y8),fsnr(app,x,Y14),fsnr(app,x,Y18)];

            app.leya.Visible="on";
            cla(app.leya,"reset");
            stem(app.leya,vc,SNR,'b'),title(app.leya,'SNR Promedio Ley A')
%             stem(app.leya,rp,SNR,'b'),title(app.leya,'SNR Promedio Ley A')
        end

        % Button pushed function: SNR_PButton_3
        function SNR_PButton_3Pushed(app, event)
%             rp=str2double(app.rpDropDown.Value());
            [x,~]=audioread("x.wav");
%             [~,Y]=fef(app,rp,x);
%             snrp=fsnr(app,x,Y);

            [~,Y4] = fef(app,4,x);
            [~,Y8] = fef(app,8,x);
            [~,Y14] = fef(app,14,x);
            [~,Y18] = fef(app,18,x);
            vc=[4 8 14 18];
            SNR=[fsnr(app,x,Y4),fsnr(app,x,Y8),fsnr(app,x,Y14),fsnr(app,x,Y18)];

            app.facfi.Visible="on";
            cla(app.facfi,"reset");
            stem(app.facfi,vc,SNR,'b'),title(app.facfi,'SNR Promedio FEF')
%             stem(app.facfi,rp,snrp,'b'),title(app.facfi,'SNR Promedio FEF')
        end

        % Button pushed function: SNR_PButton_4
        function SNR_PButton_4Pushed(app, event)
            %rp=str2double(app.rpDropDown.Value());
            [x,~]=audioread("x.wav");
            %[~,Y]=fea(app,rp,x);
            %snrp=fsnr(app,x,Y);

            [~,Y4] = fea(app,4,x);
            [~,Y8] = fea(app,8,x);
            [~,Y14] = fea(app,14,x);
            [~,Y18] = fea(app,18,x);

            vc=[4 8 14 18];
            SNR=[fsnr(app,x,Y4),fsnr(app,x,Y8),fsnr(app,x,Y14),fsnr(app,x,Y18)];
            
            app.facfi.Visible="on";
            cla(app.facada,"reset");
            stem(app.facada,vc,SNR,'b'),title(app.facada,'SNR Promedio FEA')
            %stem(app.facada,rp,snrp,'b'),title(app.facada,'SNR Promedio FEA')
        end

        % Button pushed function: SNR_BButton
        function SNR_BButtonPushed(app, event)
            
        end

        % Button pushed function: SNR_BButton_2
        function SNR_BButton_2Pushed(app, event)
            
        end

        % Button pushed function: SNR_BButton_3
        function SNR_BButton_3Pushed(app, event)
%             rp=str2double(app.rpDropDown.Value());
            [x,Fs]=audioread("x.wav");
            Tc=app.TcEditField.Value();

%             [SNR,~]=fea(app,rp,x);
            [SNR1,~] = fef(app,4,x);
            [SNR2,~] = fef(app,8,x);
            [SNR3,~] = fef(app,14,x);
            [SNR4,~] = fef(app,18,x);
            
            t=0:1/Fs:Tc-(1/Fs);

            app.facfi.Visible="on";
            cla(app.facfi,"reset");
            plot(app.facfi,t,SNR1,'b',t,SNR2,'r',t,SNR3,'g',t,SNR4,'c'),title(app.facfi,'SNR Bloque FEF')
%             plot(app.facfi,t,SNR,'b'),title(app.facfi,'SNR Bloque FEF')
        end

        % Button pushed function: SNR_BButton_4
        function SNR_BButton_4Pushed(app, event)
%             rp=str2double(app.rpDropDown.Value());
            [x,Fs]=audioread("x.wav");
%             [SNR,~]=fea(app,rp,x);
            Tc=app.TcEditField.Value();
            t=0:1/Fs:Tc-(1/Fs);

            [SNR1,~] = fea(app,4,x);
            [SNR2,~] = fea(app,8,x);
            [SNR3,~] = fea(app,14,x);
            [SNR4,~] = fea(app,18,x);

            app.facfi.Visible="on";
            cla(app.facada,"reset");
            plot(app.facada,t,SNR1,'b',t,SNR2,'r',t,SNR3,'g',t,SNR4,'c'),title(app.facada,'SNR Bloque FEF')
%             plot(app.facada,t,SNR,'b'),title(app.facada,'SNR Bloque FEA')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.leyu = uiaxes(app.UIFigure);
            title(app.leyu, 'Ley ''u''')
            zlabel(app.leyu, 'Z')
            app.leya = uiaxes(app.UIFigure);
            title(app.leya, 'Ley ''a''')
            zlabel(app.leya, 'Z')
            app.facfi = uiaxes(app.UIFigure);
            title(app.facfi, 'Factor de escala Fijo')
            zlabel(app.facfi, 'Z')
            app.facada = uiaxes(app.UIFigure);
            title(app.facada, 'Factor de escala Adaptativo')
            zlabel(app.facada, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal original')
            zlabel(app.UIAxes, 'Z')
            app.Fs80EditFieldLabel = uilabel(app.UIFigure);
            app.Fs80EditFieldLabel.Text = 'Fs (<80)';
            app.FsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TcEditFieldLabel = uilabel(app.UIFigure);
            app.TcEditFieldLabel.Text = 'Tc';
            app.TcEditField = uieditfield(app.UIFigure, 'numeric');
            app.uEditFieldLabel = uilabel(app.UIFigure);
            app.uEditFieldLabel.Text = 'u';
            app.uEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.rpDropDownLabel = uilabel(app.UIFigure);
            app.rpDropDownLabel.Text = 'rp';
            app.rpDropDown = uidropdown(app.UIFigure);
            app.rpDropDown.Items = {'4', '8', '14', '18'};
            app.rpDropDown.Value = '4';
            app.TButton = uibutton(app.UIFigure, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.TButton.Text = 'T';
            app.FButton = uibutton(app.UIFigure, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.ESPButton = uibutton(app.UIFigure, 'push');
            app.ESPButton.ButtonPushedFcn = createCallbackFcn(app, @ESPButtonPushed, true);
            app.ESPButton.Text = 'ESP';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'imagenes', 'play.jpg');
            app.Button.Text = '';
            app.TButton_2 = uibutton(app.UIFigure, 'push');
            app.TButton_2.ButtonPushedFcn = createCallbackFcn(app, @TButton_2Pushed, true);
            app.TButton_2.Text = 'T';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'imagenes', 'play.jpg');
            app.Button_3.Text = '';
            app.SNR_PButton = uibutton(app.UIFigure, 'push');
            app.SNR_PButton.ButtonPushedFcn = createCallbackFcn(app, @SNR_PButtonPushed, true);
            app.SNR_PButton.Text = 'SNR_P';
            app.SNR_BButton = uibutton(app.UIFigure, 'push');
            app.SNR_BButton.ButtonPushedFcn = createCallbackFcn(app, @SNR_BButtonPushed, true);
            app.SNR_BButton.Text = 'SNR_B';
            app.TButton_3 = uibutton(app.UIFigure, 'push');
            app.TButton_3.ButtonPushedFcn = createCallbackFcn(app, @TButton_3Pushed, true);
            app.TButton_3.Text = 'T';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'imagenes', 'play.jpg');
            app.Button_4.Text = '';
            app.SNR_PButton_2 = uibutton(app.UIFigure, 'push');
            app.SNR_PButton_2.ButtonPushedFcn = createCallbackFcn(app, @SNR_PButton_2Pushed, true);
            app.SNR_PButton_2.Text = 'SNR_P';
            app.SNR_BButton_2 = uibutton(app.UIFigure, 'push');
            app.SNR_BButton_2.ButtonPushedFcn = createCallbackFcn(app, @SNR_BButton_2Pushed, true);
            app.SNR_BButton_2.Text = 'SNR_B';
            app.TButton_4 = uibutton(app.UIFigure, 'push');
            app.TButton_4.ButtonPushedFcn = createCallbackFcn(app, @TButton_4Pushed, true);
            app.TButton_4.Text = 'T';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'imagenes', 'play.jpg');
            app.Button_5.Text = '';
            app.SNR_PButton_3 = uibutton(app.UIFigure, 'push');
            app.SNR_PButton_3.ButtonPushedFcn = createCallbackFcn(app, @SNR_PButton_3Pushed, true);
            app.SNR_PButton_3.Text = 'SNR_P';
            app.SNR_BButton_3 = uibutton(app.UIFigure, 'push');
            app.SNR_BButton_3.ButtonPushedFcn = createCallbackFcn(app, @SNR_BButton_3Pushed, true);
            app.SNR_BButton_3.Text = 'SNR_B';
            app.TButton_5 = uibutton(app.UIFigure, 'push');
            app.TButton_5.ButtonPushedFcn = createCallbackFcn(app, @TButton_5Pushed, true);
            app.TButton_5.Text = 'T';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'imagenes', 'play.jpg');
            app.Button_6.Text = '';
            app.SNR_PButton_4 = uibutton(app.UIFigure, 'push');
            app.SNR_PButton_4.ButtonPushedFcn = createCallbackFcn(app, @SNR_PButton_4Pushed, true);
            app.SNR_PButton_4.Text = 'SNR_P';
            app.SNR_BButton_4 = uibutton(app.UIFigure, 'push');
            app.SNR_BButton_4.ButtonPushedFcn = createCallbackFcn(app, @SNR_BButton_4Pushed, true);
            app.SNR_BButton_4.Text = 'SNR_B';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Icon = fullfile(pathToMLAPP, 'imagenes', 'red-circle-red-dot-icon-free.png');
            app.RecordButton.Text = 'Record  ';
            app.rDropDownLabel = uilabel(app.UIFigure);
            app.rDropDownLabel.Text = 'r';
            app.rDropDown = uidropdown(app.UIFigure);
            app.rDropDown.Items = {'8', '16', '24'};
            app.rDropDown.Value = '8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parcial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end